classdef Interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InterfazPrincipal               matlab.ui.container.TabGroup
        ConfigTab                       matlab.ui.container.Tab
        HeadingIndicator                Aero.ui.control.HeadingIndicator
        HeadingIndicatorLabel           matlab.ui.control.Label
        ArtificialHorizon               Aero.ui.control.ArtificialHorizon
        ArtificialHorizonLabel          matlab.ui.control.Label
        ClimbIndicator                  Aero.ui.control.ClimbIndicator
        ClimbIndicatorLabel             matlab.ui.control.Label
        Image4                          matlab.ui.control.Image
        RearmeButton                    matlab.ui.control.Button
        OtrosparametrosPanel            matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        Max_rel_pressureEditField       matlab.ui.control.NumericEditField
        Max_rel_pressureEditFieldLabel  matlab.ui.control.Label
        EFCompensatorEditField          matlab.ui.control.NumericEditField
        EFCompensatorEditFieldLabel     matlab.ui.control.Label
        ConexionseriePanel              matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        ConnectButton                   matlab.ui.control.Button
        ConfigButton                    matlab.ui.control.Button
        PUERTOLabel                     matlab.ui.control.Label
        BAUDIOSLabel                    matlab.ui.control.Label
        NoconectadoLamp                 matlab.ui.control.Lamp
        NoconectadoLampLabel            matlab.ui.control.Label
        LucesvisionporcomputadorPanel   matlab.ui.container.Panel
        EditB                           matlab.ui.control.NumericEditField
        EditField_3Label                matlab.ui.control.Label
        EditG                           matlab.ui.control.NumericEditField
        EditField_2Label                matlab.ui.control.Label
        EditR                           matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        GreenKnob                       matlab.ui.control.Knob
        GreenKnobLabel                  matlab.ui.control.Label
        BlueKnob                        matlab.ui.control.Knob
        BlueKnobLabel                   matlab.ui.control.Label
        RedKnob                         matlab.ui.control.Knob
        RedKnobLabel                    matlab.ui.control.Label
        BLamp                           matlab.ui.control.Lamp
        BLampLabel                      matlab.ui.control.Label
        GLamp                           matlab.ui.control.Lamp
        GLampLabel                      matlab.ui.control.Label
        RLamp                           matlab.ui.control.Lamp
        RLampLabel                      matlab.ui.control.Label
        LabelBaudios                    matlab.ui.control.Label
        VisionporcomputadorTab          matlab.ui.container.Tab
        PosicionText                    matlab.ui.control.TextArea
        BotoneraPanel                   matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        setButton                       matlab.ui.control.Button
        Z_offsetEditField               matlab.ui.control.NumericEditField
        Z_offsetEditFieldLabel          matlab.ui.control.Label
        zeroButton                      matlab.ui.control.Button
        CapturarposicionButton          matlab.ui.control.Button
        connect                         matlab.ui.control.Button
        CamarasPanel                    matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        UIAxes_xz                       matlab.ui.control.UIAxes
        UIAxes_yz                       matlab.ui.control.UIAxes
        RecogidadedatosTab              matlab.ui.container.Tab
        Modo_manualSwitch               matlab.ui.control.Switch
        Modo_manualSwitchLabel          matlab.ui.control.Label
        ControlporcentualSwitch         matlab.ui.control.Switch
        ControlporcentualSwitchLabel    matlab.ui.control.Label
        DatosdeentradaPanel             matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        NombrevariableEditField         matlab.ui.control.EditField
        NombrevariableEditFieldLabel    matlab.ui.control.Label
        TempambienteEditField           matlab.ui.control.NumericEditField
        TempambienteEditFieldLabel      matlab.ui.control.Label
        PpresostatoMpaEditField         matlab.ui.control.NumericEditField
        PpresostatoMpaEditFieldLabel    matlab.ui.control.Label
        NumerodemuestrasEditField       matlab.ui.control.NumericEditField
        NumerodemuestrasEditFieldLabel  matlab.ui.control.Label
        Pmax_S3                         matlab.ui.control.NumericEditField
        Pmax_S3EditFieldLabel           matlab.ui.control.Label
        Pmin_S3                         matlab.ui.control.NumericEditField
        Pmin_S3EditFieldLabel           matlab.ui.control.Label
        Pmax_S2                         matlab.ui.control.NumericEditField
        Pmax_S2EditFieldLabel           matlab.ui.control.Label
        Pmin_S2                         matlab.ui.control.NumericEditField
        Pmin_S2EditFieldLabel           matlab.ui.control.Label
        Pmax_S1                         matlab.ui.control.NumericEditField
        Pmax_S1EditField_2Label         matlab.ui.control.Label
        Pmin_S1                         matlab.ui.control.NumericEditField
        Pmin_S1EditFieldLabel           matlab.ui.control.Label
        CoordenadashomogeneasPanel      matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        datosmth                        matlab.ui.control.UIAxes
        VisionporcomputadorPanel        matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        datosyz                         matlab.ui.control.UIAxes
        datosxz                         matlab.ui.control.UIAxes
        Lamp_2                          matlab.ui.control.Lamp
        Label_2                         matlab.ui.control.Label
        ManualPanel                     matlab.ui.container.Panel
        GuardarButton                   matlab.ui.control.Button
        DesinflarButton                 matlab.ui.control.Button
        TomarposicionButton             matlab.ui.control.Button
        EnviarButton                    matlab.ui.control.Button
        v9                              matlab.ui.control.NumericEditField
        v8                              matlab.ui.control.NumericEditField
        v7                              matlab.ui.control.NumericEditField
        v6                              matlab.ui.control.NumericEditField
        V1EditField_3Label_2            matlab.ui.control.Label
        V1EditField_6                   matlab.ui.control.NumericEditField
        v5                              matlab.ui.control.NumericEditField
        V1EditField_2Label_2            matlab.ui.control.Label
        v4                              matlab.ui.control.NumericEditField
        v3                              matlab.ui.control.NumericEditField
        V3EditFieldLabel                matlab.ui.control.Label
        v2                              matlab.ui.control.NumericEditField
        V2EditFieldLabel                matlab.ui.control.Label
        v1                              matlab.ui.control.NumericEditField
        V1EditFieldLabel                matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        TextAreaLabel                   matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        SetButton                       matlab.ui.control.Button
        ModomanualTab                   matlab.ui.container.Tab
        Image5                          matlab.ui.control.Image
        PresionaenviarCuandosecontrolamanualmenteSlider  matlab.ui.control.Slider
        PresionaenviarCuandosecontrolamanualmenteSliderLabel  matlab.ui.control.Label
        Tabsmodomanual                  matlab.ui.container.TabGroup
        ControlporcentualTab            matlab.ui.container.Tab
        GradosdelibertadPanel           matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        S33Knob                         matlab.ui.control.Knob
        S33KnobLabel                    matlab.ui.control.Label
        S32Knob                         matlab.ui.control.Knob
        S32KnobLabel                    matlab.ui.control.Label
        S31Knob                         matlab.ui.control.Knob
        S31KnobLabel                    matlab.ui.control.Label
        S23Knob                         matlab.ui.control.Knob
        S23KnobLabel                    matlab.ui.control.Label
        S13Knob                         matlab.ui.control.Knob
        S13KnobLabel                    matlab.ui.control.Label
        S22Knob                         matlab.ui.control.Knob
        S22KnobLabel                    matlab.ui.control.Label
        S21Knob                         matlab.ui.control.Knob
        S21KnobLabel                    matlab.ui.control.Label
        S12Knob                         matlab.ui.control.Knob
        S12KnobLabel                    matlab.ui.control.Label
        S11Knob                         matlab.ui.control.Knob
        S11KnobLabel                    matlab.ui.control.Label
        ControlRadioanguloTab           matlab.ui.container.Tab
        SendButton                      matlab.ui.control.Button
        ControlesPanel                  matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        Alpha3Knob                      matlab.ui.control.Knob
        Alpha3KnobLabel                 matlab.ui.control.Label
        R3Knob                          matlab.ui.control.Knob
        R3KnobLabel                     matlab.ui.control.Label
        Alpha2Knob                      matlab.ui.control.Knob
        Alpha2KnobLabel                 matlab.ui.control.Label
        R2Knob                          matlab.ui.control.Knob
        R2KnobLabel                     matlab.ui.control.Label
        Alpha1Knob                      matlab.ui.control.Knob
        Alpha1KnobLabel                 matlab.ui.control.Label
        R1Knob                          matlab.ui.control.Knob
        R1KnobLabel                     matlab.ui.control.Label
        Segmento0Tab                    matlab.ui.container.Tab
        sendV2                          matlab.ui.control.Button
        sendV3                          matlab.ui.control.Button
        sendV1                          matlab.ui.control.Button
        GaugeV3                         matlab.ui.control.Gauge
        V2Label                         matlab.ui.control.Label
        GaugeV2                         matlab.ui.control.Gauge
        V2Gauge_4Label                  matlab.ui.control.Label
        GaugeV1                         matlab.ui.control.Gauge
        V1Gauge_2Label                  matlab.ui.control.Label
        Segmento1Tab                    matlab.ui.container.Tab
        GaugeV6                         matlab.ui.control.Gauge
        V0Gauge_3Label_2                matlab.ui.control.Label
        GaugeV5                         matlab.ui.control.Gauge
        V5GaugeLabel                    matlab.ui.control.Label
        GaugeV4                         matlab.ui.control.Gauge
        V4Gauge_2Label                  matlab.ui.control.Label
        sendV5                          matlab.ui.control.Button
        sendV6                          matlab.ui.control.Button
        sendV4                          matlab.ui.control.Button
        Segmento2Tab                    matlab.ui.container.Tab
        GaugeV9                         matlab.ui.control.Gauge
        V0Gauge_3Label_4                matlab.ui.control.Label
        GaugeV8                         matlab.ui.control.Gauge
        V8GaugeLabel                    matlab.ui.control.Label
        GaugeV7                         matlab.ui.control.Gauge
        V0Gauge_3Label_3                matlab.ui.control.Label
        sendV8                          matlab.ui.control.Button
        sendV9                          matlab.ui.control.Button
        sendV7                          matlab.ui.control.Button
        Tab_2                           matlab.ui.container.Tab
        Tab                             matlab.ui.container.Tab
        TabGroup                        matlab.ui.container.TabGroup
        AjustesTab                      matlab.ui.container.Tab
        CapturadedatosTab               matlab.ui.container.Tab
        ControlmediantetablaTab         matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        CargardatasetTab                matlab.ui.container.Tab
        cargardatasetsPanel             matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        CargarButton                    matlab.ui.control.Button
        ActualizarButton                matlab.ui.control.Button
        DatasetsListBox                 matlab.ui.control.ListBox
        FiltroTab                       matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        ResultadosEditField             matlab.ui.control.NumericEditField
        ResultadosEditFieldLabel        matlab.ui.control.Label
        FiltrarButton                   matlab.ui.control.Button
        E3_maxSlider                    matlab.ui.control.Slider
        E3_maxSlider_2Label             matlab.ui.control.Label
        E2_maxSlider                    matlab.ui.control.Slider
        E2_maxSliderLabel               matlab.ui.control.Label
        E3_minSlider                    matlab.ui.control.Slider
        E3_minSliderLabel               matlab.ui.control.Label
        E2_minSlider                    matlab.ui.control.Slider
        E2_minSliderLabel               matlab.ui.control.Label
        E1_maxSlider                    matlab.ui.control.Slider
        E1_maxSliderLabel               matlab.ui.control.Label
        E1_minSlider                    matlab.ui.control.Slider
        E1_minSliderLabel               matlab.ui.control.Label
        Z_maxSlider                     matlab.ui.control.Slider
        Z_maxSliderLabel                matlab.ui.control.Label
        Z_minSlider                     matlab.ui.control.Slider
        Z_minSliderLabel                matlab.ui.control.Label
        y_maxSlider                     matlab.ui.control.Slider
        y_maxSliderLabel                matlab.ui.control.Label
        Y_minSlider                     matlab.ui.control.Slider
        Y_minSliderLabel                matlab.ui.control.Label
        X_maxSlider                     matlab.ui.control.Slider
        X_maxSliderLabel                matlab.ui.control.Label
        X_minSlider                     matlab.ui.control.Slider
        X_minSliderLabel                matlab.ui.control.Label
        BuscadorTab                     matlab.ui.container.Tab
        OutputTextArea                  matlab.ui.control.TextArea
        BuscadordepuntosPanel           matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        BuscarButton                    matlab.ui.control.Button
        AnadirButton                    matlab.ui.control.Button
        E3Knob                          matlab.ui.control.Knob
        E3KnobLabel                     matlab.ui.control.Label
        E2Knob                          matlab.ui.control.Knob
        E2KnobLabel                     matlab.ui.control.Label
        E1Knob                          matlab.ui.control.Knob
        E1KnobLabel                     matlab.ui.control.Label
        ZEditField_2                    matlab.ui.control.NumericEditField
        ZEditField_2Label               matlab.ui.control.Label
        YEditField_2                    matlab.ui.control.NumericEditField
        YEditField_2Label               matlab.ui.control.Label
        XEditField_2                    matlab.ui.control.NumericEditField
        XEditField_2Label               matlab.ui.control.Label
        ControlTab                      matlab.ui.container.Tab
        PuntosPanel                     matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        DesinflarButton_2               matlab.ui.control.Button
        IrButton                        matlab.ui.control.Button
        TextArea_2                      matlab.ui.control.TextArea
        TextArea_2Label                 matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        ListBoxLabel                    matlab.ui.control.Label
        VisualizaciondeposicionesPanel  matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        Pos_filtradas                   matlab.ui.control.UIAxes
        Pos_disponibles                 matlab.ui.control.UIAxes
        ControlmedianteIATab            matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        aunpuntoTab                     matlab.ui.container.Tab
        PosicioninicialPanel            matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        SolucionButton                  matlab.ui.control.Button
        E3EditField                     matlab.ui.control.NumericEditField
        E3EditFieldLabel                matlab.ui.control.Label
        E2EditField                     matlab.ui.control.NumericEditField
        E2EditFieldLabel                matlab.ui.control.Label
        E1EditField                     matlab.ui.control.NumericEditField
        E1EditFieldLabel                matlab.ui.control.Label
        ZEditField                      matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        pipfTab                         matlab.ui.container.Tab
    end

    
    %Atributos publicos
    properties (Access = public) 
        mi_arduino; % El puerto serial para acceder desde más apps
        LosBaudios = 9600; %El dato de los Baudios que actualizaremos en otra app
        ElPuerto = "COM22"; %El dato del puerto del arduino que actualizaremos en otra app
        configset = false; 
        caso = '2';
        
        %Presion que se va a enviar
        millis2send = 0;

      
        %yz -> logitech
        %xz -> la otra

        millisSendedToValves;
            
        gaugearray;
        justSended = [0 0 0 0 0 0 0 0 0 ];


        img_yz;
        img_xz;
        cam_yz;
        cam_xz;
        cameraParams_yz;
        cameraParams_xz;

        rotyz;
        rotxz;
        transyz;
        transxz;
        matyz;
        matxz;
        p;
        o;
        r;
        g;
        b;
        

        rot_m;
        euler_m

        dy = 0;
        dx = 0;
        dz = 0;
        d = 245;
        z_offset = 0;


        % Datos recogidos
        pmin = [100 100 100]; %S1 S2 S3
        pmax = [300 300 300];
        n = 10; % Numero de muestras
        temp_amb = 20; % temperatura ambiente
        p_presostato = 0.1; % presion del presostato
        elapsed_time = 0; % tiempo transcurrido

        nom = 'dataset';
        mydata

        rd_c = 0;
        stopped = 0;


        % Movidas control medianta tabla
        datasets_list
        datasets_cargados
        dataset_filtrado

        punto_encontrado
        puntos_ir
        number_of_points = 1;


    end   
    
    properties (Access = private)
        Property43 % Description
    end


    methods (Access = public)
    
        function updateplot(app, baudio, com) %funcion para actualizar valores desde otra app
            
            app.LosBaudios = baudio;   %almacenamos las variables como propiedades            
            app.ElPuerto = com;
            app.configset = true;
            
            
            app.ConfigButton.Enable = true;     %desbloqueamos este boton al cerrar la otra app
            app.ConnectButton.Enable = true;
        end
        
    end


    methods (Access = private)
        
        % Actualiza los visores de presiones en el modo manual
        function uptdateGauges(app)
            
            aux = app.millisSendedToValves;
            
            app.GaugeV1.Value = aux(1);
            app.GaugeV2.Value = aux(2);
            app.GaugeV3.Value = aux(3);

            app.GaugeV4.Value = aux(4);
            app.GaugeV5.Value = aux(5);
            app.GaugeV6.Value = aux(6);

            app.GaugeV7.Value = aux(7);
            app.GaugeV8.Value = aux(8);
            app.GaugeV9.Value = aux(9);

             
        end
        
        function str = point2str(~, point)
            str = "(" + point(1) + ", " + point(2) + ", " + point(3) + ")";
            
        end
        
      
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.millisSendedToValves = [0 0 0 0 0 0 0 0 0];
            app.StartButton.Enable = "off";
            app.DesinflarButton.Enable = "off";
           
            
            %app.EnviarButton.Enable = "off";
            app.TomarposicionButton.Enable = "off";
            app.GuardarButton.Enable = "off";	


            %app.zeroButton.Enable = false;
            %app.CapturarposicionButto.Enable = false;

            
            % Toma de datos
            app.NumerodemuestrasEditField.Value = app.n;
            
            app.PpresostatoMpaEditField.Value = app.p_presostato;
            app.TempambienteEditField.Value = app.temp_amb;
            app.NombrevariableEditField.Value = app.nom;
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            try
                
                %app.mi_arduino=serialport(app.ElPuerto,app.LosBaudios);
                app.mi_arduino = serialport('COM22', 9600);
                %configureTerminator(app.mi_arduino,"CR/LF", "LF");
                %configureTerminator(app.mi_arduino,"CR/LF");
                %flush(app.mi_arduino);                
               
                %app.ConfigButton.Enable = false;
                %app.ConectarBoton.Enable = false;
                %pause(2);
                app.NoconectadoLamp.Color = 'green';
                app.EnviarButton.Enable = "on";
                configureTerminator(obj,"CR/LF", "LF");
                app.EnviarButton.Enable = true;
                
                
            catch
                VentanaAdvertir;


            end
        end

        % Button pushed function: ConfigButton
        function ConfigButtonPushed(app, event)
            
            app.ConnectButton.Enable = false;
            VentanaArduino(app);
        

        end

        % Value changed function: 
        % PresionaenviarCuandosecontrolamanualmenteSlider
        function PresionaenviarCuandosecontrolamanualmenteSliderValueChanged(app, event)

            app.millis2send = app.PresionaenviarCuandosecontrolamanualmenteSlider.Value;
        end

        % Button pushed function: sendV1
        function sendV1ButtonPushed(app, event)

            writeOneValveMillis(app.mi_arduino , 0, app.millis2send);
            app.millisSendedToValves(1) = app.millis2send + app.millisSendedToValves(1);
            app.uptdateGauges();
        end

        % Button pushed function: sendV2
        function sendV2ButtonPushed(app, event)
            writeOneValveMillis(app.mi_arduino , 1, app.millis2send);
            app.millisSendedToValves(2) = app.millis2send + app.millisSendedToValves(2);
            app.uptdateGauges();
        end

        % Button pushed function: sendV3
        function sendV3ButtonPushed(app, event)
            writeOneValveMillis(app.mi_arduino , 2, app.millis2send);
            app.millisSendedToValves(3) = app.millis2send + app.millisSendedToValves(3);
            app.uptdateGauges();
        end

        % Button pushed function: sendV4
        function sendV4ButtonPushed(app, event)
            writeOneValveMillis(app.mi_arduino , 3, app.millis2send);
            app.millisSendedToValves(4) = app.millis2send + app.millisSendedToValves(4);
            app.uptdateGauges();
        end

        % Button pushed function: sendV5
        function sendV5ButtonPushed(app, event)
            writeOneValveMillis(app.mi_arduino , 4, app.millis2send);
            app.millisSendedToValves(5) = app.millis2send + app.millisSendedToValves(5);
            app.uptdateGauges();
        end

        % Button pushed function: sendV6
        function sendV6ButtonPushed(app, event)
            writeOneValveMillis(app.mi_arduino , 5, app.millis2send);
            app.millisSendedToValves(6) = app.millis2send + app.millisSendedToValves(6);
            app.uptdateGauges();
        end

        % Button pushed function: sendV7
        function sendV7ButtonPushed(app, event)
            writeOneValveMillis(app.mi_arduino , 6, app.millis2send);
            app.millisSendedToValves(7) = app.millis2send + app.millisSendedToValves(7);
            app.uptdateGauges();
        end

        % Button pushed function: sendV8
        function sendV8ButtonPushed(app, event)
            writeOneValveMillis(app.mi_arduino , 7, app.millis2send);
            app.millisSendedToValves(8) = app.millis2send + app.millisSendedToValves(8);
            app.uptdateGauges();
        end

        % Button pushed function: sendV9
        function sendV9ButtonPushed(app, event)
            writeOneValveMillis(app.mi_arduino , 8, app.millis2send);
            app.millisSendedToValves(9) = app.millis2send + app.millisSendedToValves(9);
            app.uptdateGauges();
        end

        % Button pushed function: CapturarposicionButton
        function CapturarposicionButtonPushed(app, event)
            
            % Tomamos imagen con la camara 1 y 2S
            app.img_yz = snapshot(app.cam_yz);
            app.img_xz = snapshot(app.cam_xz);

            imshow(app.img_yz,'Parent',app.UIAxes_yz);
            imshow(app.img_xz, 'Parent', app.UIAxes_xz);
            
            %para el verde, rojo y azul, capturamos la posicion del punto
            % Buscamos los puntos rojo, verde y azul en las dos imagenes.
            yz_r = findPoint(app.img_yz, 'o',"yz");
            yz_g = findPoint(app.img_yz, 'g',"yz");
            yz_b = findPoint(app.img_yz, 'b',"yz");
            xz_r = findPoint(app.img_xz, 'o',"xz");
            xz_g = findPoint(app.img_xz, 'g',"xz");
            xz_b = findPoint(app.img_xz, 'b',"xz");


            % Ploteamos las cosas en los ejes
            hold(app.UIAxes_yz, 'on')
            hold(app.UIAxes_xz, 'on')
            plot(yz_g(1) ,yz_g(2), 'o', 'Parent',app.UIAxes_yz, 'Color','green');
            plot(xz_g(1), xz_g(2), 'o', 'Parent',app.UIAxes_xz, 'Color','green')
            plot(yz_b(1), yz_b(2), 'o', 'Parent',app.UIAxes_yz, 'Color','red');
            plot(xz_b(1), xz_b(2), 'o', 'Parent',app.UIAxes_xz, 'Color','red')
            plot(yz_r(1), yz_r(2), 'o', 'Parent',app.UIAxes_yz, 'Color','red');
            plot(xz_r(1), xz_r(2), 'o', 'Parent',app.UIAxes_xz, 'Color','red')
            plot([yz_b(1) yz_g(1)], [yz_b(2) yz_g(2)], 'g', 'Parent',app.UIAxes_yz);
            plot([yz_b(1) yz_r(1)], [yz_b(2) yz_r(2)], 'g', 'Parent',app.UIAxes_yz);
            plot([yz_g(1) yz_r(1)], [yz_g(2) yz_r(2)], 'g', 'Parent',app.UIAxes_yz);
            plot([xz_b(1) xz_g(1)], [xz_b(2) xz_g(2)], 'g', 'Parent',app.UIAxes_xz);
            plot([xz_b(1) xz_r(1)], [xz_b(2) xz_r(2)], 'g', 'Parent',app.UIAxes_xz);
            plot([xz_g(1) xz_r(1)], [xz_g(2) xz_r(2)], 'g', 'Parent',app.UIAxes_xz);
            
            
            app.r = getCoordinates(yz_r,xz_r,app.matyz, app.matxz, app.dx, app.dy, app.dz, app.z_offset);
            app.g = getCoordinates(yz_g,xz_g,app.matyz, app.matxz, app.dx, app.dy, app.dz, app.z_offset);
            app.b = getCoordinates(yz_b,xz_b,app.matyz, app.matxz, app.dx, app.dy, app.dz, app.z_offset);
            
           
            hold on

            
            [app.rot_m, app.euler_m] = getRotations(app.r, app.g, app.b);
            
            % Pasamos matriz de rotacion a cuaternios, 
            quaternion = rotm2quat(app.rot_m);
            disp(quaternion);
            hold(app.UIAxes_yz, 'on');
                
            % Pasamos matriz de rotacion a angulos de euler
            euler = rotm2eul(app.rot_m);

            hold(app.UIAxes_yz, "on");
            
            rv = [app.r(1) - app.g(1), app.r(2) - app.g(2), app.r(3) - app.g(3)];
            bv = [app.b(1) - app.g(1), app.b(2) - app.g(2), app.b(3) - app.g(3)];
            zv = cross(rv, bv);
            rv = rv / norm(rv);
            bv = bv / norm(bv);

            zv = zv / norm(zv);


            quiver3(app.g(1), app.g(2), app.g(3), rv(1), rv(2), rv(3), 'Parent', app.UIAxes_yz, 'Color', 'red');
            quiver3(app.g(1), app.g(2), app.g(3), bv(1) , bv(2), bv(3), 'Parent', app.UIAxes_yz, 'Color', 'blue');
            quiver3(app.g(1), app.g(2), app.g(3), zv(1) , zv(2), zv(3), 'Parent', app.UIAxes_yz, 'Color', 'green');


            %plot(app.p(2),app.p(3), 'o', 'Parent',app.UIAxes_yz);

            %plot(app.p(1),app.p(2), 'o', 'Parent',app.UIAxes_xz);
            valuestr = "Green: " + num2str(app.g(1)) + ", " + num2str(app.g(2)) + ", " + num2str(app.g(3)) + newline + "red: " + num2str(app.r(1)) + ", " + num2str(app.r(2)) + ", " + num2str(app.r(3)) + newline + "blue: " + num2str(app.b(1)) + ", " + num2str(app.b(2)) + ", " + num2str(app.b(3)) + newline + "euler: " + num2str(rad2deg(euler(1))) + ", " + num2str(rad2deg(euler(2))) + ", " + num2str(rad2deg(euler(3)))+   newline +    " dy = " + num2str(app.dy) + "" +  " dx = " + num2str(app.dx);
            app.PosicionText.Value = valuestr;


        end

        % Button pushed function: connect
        function connectButtonPushed(app, event)
            app.cam_xz = webcam("USB Camera");
            app.cam_yz = webcam("Logitech HD Webcam C270");
            tempX = load('C:\TFG_software\Code_matlab\par.mat','cameraParams');
            app.cameraParams_yz = tempX.cameraParams;
            tempX = load('C:\TFG_software\Code_matlab\par.mat','cameraParams_xz');
            app.cameraParams_xz = tempX.cameraParams_xz;

            
            [app.rotyz, app.transyz] = findCameraPose(app.cam_yz, app.cameraParams_yz);
            [app.rotxz, app.transxz] = findCameraPose(app.cam_xz, app.cameraParams_xz);
            
            
            figure;
            
            app.matyz = cameraMatrix(app.cameraParams_yz, app.rotyz, app.transyz);
            app.matxz = cameraMatrix(app.cameraParams_xz, app.rotxz, app.transxz);            
            

            app.BLamp.Color = "Green";

            hold off
        end

        % Button pushed function: zeroButton
        function zeroButtonPushed(app, event)
        %Objetivo es transformar las matrices de rotacion de las dos
        %camaras para hacer home del origen

            app.dx = 0;
            app.dy = 0;
            app.dz = 0;
            % Tomamos imagenes con las dos camaras
            app.img_yz = snapshot(app.cam_yz);
            app.img_xz = snapshot(app.cam_xz);
            imshow(app.img_yz,'Parent',app.UIAxes_yz);
            imshow(app.img_xz, 'Parent', app.UIAxes_xz);
            
            % Buscamos los puntos rojo, verde y azul en las dos imagenes.
            yz_r = findPoint(app.img_yz, 'o',"yz");
            yz_g = findPoint(app.img_yz, 'g',"yz");
            yz_b = findPoint(app.img_yz, 'b',"yz");
            xz_r = findPoint(app.img_xz, 'o',"xz");
            xz_g = findPoint(app.img_xz, 'g',"xz");
            xz_b = findPoint(app.img_xz, 'b',"xz");

            % Encontramos las coordenadas de dichos puntos respecto al
            % sistema fijo
            app.r = getCoordinates(yz_r,xz_r,app.matyz, app.matxz, app.dx, app.dy, app.dz, app.z_offset);
            app.g = getCoordinates(yz_g,xz_g,app.matyz, app.matxz, app.dx, app.dy, app.dz, app.z_offset);
            app.b = getCoordinates(yz_b,xz_b,app.matyz, app.matxz, app.dx, app.dy, app.dz, app.z_offset);

            % Los dibujamos en los ejes
            hold(app.UIAxes_yz, 'on')
            hold(app.UIAxes_xz, 'on')
            plot(yz_g(1) ,yz_g(2), 'o', 'Parent',app.UIAxes_yz, 'Color','green');
            plot(xz_g(1), xz_g(2), 'o', 'Parent',app.UIAxes_xz, 'Color','green')
            plot(yz_b(1), yz_b(2), 'o', 'Parent',app.UIAxes_yz, 'Color','red');
            plot(xz_b(1), xz_b(2), 'o', 'Parent',app.UIAxes_xz, 'Color','red')
            plot(yz_r(1), yz_r(2), 'o', 'Parent',app.UIAxes_yz, 'Color','red');
            plot(xz_r(1), xz_r(2), 'o', 'Parent',app.UIAxes_xz, 'Color','red')
            plot([yz_b(1) yz_g(1)], [yz_b(2) yz_g(2)], 'g', 'Parent',app.UIAxes_yz);
            plot([yz_b(1) yz_r(1)], [yz_b(2) yz_r(2)], 'g', 'Parent',app.UIAxes_yz);
            plot([yz_g(1) yz_r(1)], [yz_g(2) yz_r(2)], 'g', 'Parent',app.UIAxes_yz);
            plot([xz_b(1) xz_g(1)], [xz_b(2) xz_g(2)], 'g', 'Parent',app.UIAxes_xz);
            plot([xz_b(1) xz_r(1)], [xz_b(2) xz_r(2)], 'g', 'Parent',app.UIAxes_xz);
            plot([xz_g(1) xz_r(1)], [xz_g(2) xz_r(2)], 'g', 'Parent',app.UIAxes_xz);
            
            app.dx = app.g(1);
            app.dy = app.g(2);
            app.dz = app.g(3);


        end

        % Button pushed function: setButton
        function setButtonPushed(app, event)
            app.z_offset = app.Z_offsetEditField.Value;
            
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            app.pmax(1) = app.Pmax_S1.Value;
            app.pmax(2) = app.Pmax_S2.Value;
            app.pmax(3) = app.Pmax_S3.Value;
            app.pmin(1) = app.Pmin_S1.Value;
            app.pmin(2) = app.Pmin_S2.Value;
            app.pmin(3) = app.Pmin_S3.Value;
            
            
            app.n = app.NumerodemuestrasEditField.Value;
            app.nom = app.NombrevariableEditField.Value;
            app.p_presostato = app.PpresostatoMpaEditField.Value;
            app.temp_amb = app.TempambienteEditField.Value;
         

            app.TextArea.Value = "Numero de muestras = " + num2str(app.n) + newline +  "Presion maxima (S1 S2 S3) = (" + num2str(app.pmax(1)) + ", "+  num2str(app.pmax(2))+ ", "+ num2str(app.pmax(3))+ ") ms" + newline + "Presion minima (S1 S2 S3)= (" + num2str(app.pmin(1))  + ", " + num2str(app.pmin(2)) + ", " + num2str(app.pmin(3)) + ") ms" + newline + newline + "Modo porcentual: " + app.ControlporcentualSwitch.Value + newline + "Nombre: " + "dataset_" + app.nom + ".mat"+ newline + "P. presostato = " + num2str(app.p_presostato) + " Mpa" + newline + "T. Amb = " + num2str(app.temp_amb) + "ºC" +  newline + newline + "La presion se expresa en los milisegundos que permanece abierta la valvula. Esta medida esta directamente relacionada con la presion que se provoca en la vejiga correspondiente";
            app.StartButton.Enable = "on";

            app.rd_c = 1;
        end

        % Size changed function: ConfigTab
        function ConfigTabSizeChanged(app, event)
            
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.rd_c == 0
                app.TextArea.Value = "ERROR: no se han configurado los parametros";
            else
                % variable que contiene la cuenta del numero de muestra
                i = 1;


                % str que se envia para desinflar completamente el robot
                millisdesinflar = "w,1,-2000,-2000,-2000,-2000,-2000,-2000,-2000,-2000,-2000";
                
                % Desinflamos

                if app.ControlporcentualSwitch.Value == "On"
                        
                   time = 0.1;
                   for j = 1:9
                    writeline(app.mi_arduino, "x," + num2str(j -1 ) + "," + 0);
                    pause(time);
                   end
                
                else
                    writeline(app.mi_arduino, millisdesinflar)
                end


                pause(2);
                
                %almacenamos datos
                data.pmax(1:3) = app.pmax(1:3);
                data.pmin(1:3) = app.pmin(1:3);
                data.nummuestras = app.n;
                data.temp_amb = app.temp_amb;
                data.presion_presostato = app.p_presostato;
                data.controlporcentual = app.ControlporcentualSwitch.Value;
                data.maxRpressure = app.Max_rel_pressureEditField.Value;

                % Inicializamos cronometro
                tic;

               
                % Para casa muestra tomada
                for i = 1:app.n
                    
                    string2send = "w,0";
                    
                    % Se determina que vejiga se queda sin hinchar de cada segmento
                    sin_hinchar = round(2* rand(1, 9) + 1); 
                    
                    millis2send_ = ones(3);

                    % Generamos la combinacion de presiones que se enviara
                    
                    % Para cada segmento
                    for j = 1:3
                        % Para cada grado de libertad de cada segmento
                        for k = 1:3
                            if sin_hinchar(j) == k
                                millis2send_(j,k) = 0;
                            else    
                                millis2send_(j,k) = round((app.pmax(j) - app.pmin(j)) * rand(1:1) + app.pmin(j));
                            end
                        
                        end
                    end

                    % Ponemos a 0 los segmentos que no queremos que se
                    % hinchen
                    disp(millis2send_)
                   

                    millis2send__ = diag(ones(3));
                    millis2send__(1:3) = millis2send_(1,:);
                    millis2send__(4:6) = millis2send_(2,:);
                    millis2send__(7:9) = millis2send_(3,:); 
 
                    % Almacenamos presiones enviadas
                    data.inputs(i,1:9) = millis2send__(1:9);
    
                    pause(0.1);
                    

                    disp(string2send);
                    %Movemos a posicion indicada

                    if app.ControlporcentualSwitch.Value == "On"
                        
                        time = 0.05;
                       for j = 1:9
                        writeline(app.mi_arduino, "x," + num2str(j -1 ) + "," + millis2send__(j));
                        string2send = string2send + "," +  num2str( millis2send__(j));
                        pause(time);
                       end
                    
                    else
                        for j = 1:9
                        string2send = string2send + "," +  num2str( millis2send__(j));
                        end
                        writeToarduino(app.mi_arduino,string2send);
                    end


                    pause(max(millis2send__)/1000);

                    % Tomamos las imagenes
                    app.img_yz = snapshot(app.cam_yz);
                    img_name_yz = "cam_yz" + num2str(i) + ".jpeg";
                    imwrite(app.img_yz, img_name_yz, 'jpeg');
                    app.img_xz = snapshot(app.cam_xz);
                    img_name_xz = "cam_xz" + num2str(i) + ".jpeg";
                    imwrite(app.img_xz, img_name_xz, 'jpeg');

                    imshow(app.img_yz,'Parent',app.datosyz);
                    imshow(app.img_xz, 'Parent', app.datosxz);

                    hold(app.datosyz, 'on')
                    hold(app.datosxz, 'on')


                    %buscamos las marcas en la imagen yz
                    blue_yz = findPoint(app.img_yz, 'b', "yz"); 
                    data.img_points.yz.blue(i,1:2) = blue_yz;

                    green_yz = findPoint(app.img_yz, 'g',"yz"); 
                    data.img_points.yz.green(i,1:2) = green_yz;

                    red_yz = findPoint(app.img_yz, 'o', "yz"); 
                    data.img_points.yz.red(i,1:2) = red_yz;

                    %buscamos las marcas en la imagen xz
                    blue_xz = findPoint(app.img_xz, 'b', "xz"); 
                    data.img_points.xz.blue(i,1:2) = blue_xz;

                    green_xz = findPoint(app.img_xz, 'g', "yz"); 
                    data.img_points.xz.green(i,1:2) = green_xz;

                    red_xz = findPoint(app.img_xz, 'o', "yz"); 
                    data.img_points.xz.red(i,1:2) = red_xz;


                    plot(green_yz(1) ,green_yz(2), 'o', 'Parent', app.datosyz , 'Color','green');
                    plot(green_xz(1), green_xz(2), 'o', 'Parent',app.datosxz, 'Color','green')
                    plot(blue_yz(1), blue_yz(2), 'o', 'Parent',app.datosyz, 'Color','red');
                    plot(blue_xz(1), blue_xz(2), 'o', 'Parent',app.datosxz, 'Color','red')
                    plot(red_yz(1), red_yz(2), 'o', 'Parent',app.datosyz, 'Color','red');
                    plot(red_xz(1), red_xz(2), 'o', 'Parent',app.datosxz, 'Color','red')
                    plot([blue_yz(1) green_yz(1)], [blue_yz(2) green_yz(2)], 'g', 'Parent',app.datosyz);
                    plot([blue_yz(1) red_yz(1)], [blue_yz(2) red_yz(2)], 'g', 'Parent',app.datosyz);
                    plot([green_yz(1) red_yz(1)], [green_yz(2) red_yz(2)], 'g', 'Parent',app.datosyz);
                    plot([blue_xz(1) green_xz(1)], [blue_xz(2) green_xz(2)], 'g', 'Parent',app.datosxz);
                    plot([blue_xz(1) red_xz(1)], [blue_xz(2) red_xz(2)], 'g', 'Parent',app.datosxz);
                    plot([green_xz(1) red_xz(1)], [green_xz(2) red_xz(2)], 'g', 'Parent',app.datosxz);

                    
                    % Posicionamos las marcas en el sistema del mundo real
                    red = getCoordinates(red_yz, red_xz, app.matyz, app.matxz, app.dx,app.dy,app.dz, app.z_offset);
                    blue = getCoordinates(blue_yz, blue_xz, app.matyz, app.matxz, app.dx,app.dy,app.dz, app.z_offset);
                    green = getCoordinates(green_yz, green_xz, app.matyz, app.matxz, app.dx,app.dy,app.dz, app.z_offset);
                    data.points.red(i,1:3) = red;
                    data.points.blue(i,1:3) = blue;
                    data.points.green(i,1:3) = green;

                    % Obtenemos las rotaciones
                    [app.rot_m, app.euler_m] = getRotations(red, green, blue);    
                    data.outputs(i,1:3) = green(1:3);
                    data.outputs(i,4:6) = app.euler_m(1:3);


                    str2display = "Numero de muestra: " + i + newline + "Coordenadas azul: " +  app.point2str(blue) + newline + "Coordenadas verde: " + app.point2str(green) + newline + "Coordenadas azul: " + app.point2str(blue) + newline                         + "Presiones enviadas: " + string2send + newline  + "Angulos euler: " + num2str(rad2deg(app.euler_m(1))) + ", "  + num2str(rad2deg(app.euler_m(2))) + ", "  + num2str(rad2deg(app.euler_m(3)));

                    app.TextArea.Value = str2display;


                    % Almacenamos contador en la estructura de datos
                    data.index(i) = i;
                    

                    % Desinflamos
                    
                    if app.ControlporcentualSwitch.Value == "On"
                        
                       time = 0.01;
                       for j = 1:9
                        writeline(app.mi_arduino, "x," + num2str(j -1 ) + "," + 0);
                        pause(time);
                       end
                
                    else
                        writeline(app.mi_arduino, millisdesinflar)
                    end

                    pause(2 *2);


                    rv = [red(1) - green(1), red(2) - green(2), red(3) - green(3)];
                    
                    bv = [blue(1) - green(1), blue(2) - green(2), blue(3) - green(3)];
                    zv = cross(rv, bv);
                    rv = rv / norm(rv);
                    bv = bv / norm(bv);
        
                    zv = zv / norm(zv); 


                  quiver3(green(1), green(2), green(3), rv(1), rv(2), rv(3), 'Parent', app.datosmth, 'Color', 'red');
                  hold(app.datosmth, "on");
                  quiver3(green(1), green(2), green(3), bv(1) , bv(2), bv(3), 'Parent', app.datosmth, 'Color', 'blue');
                    quiver3(green(1), green(2), green(3), zv(1) , zv(2), zv(3), 'Parent', app.datosmth, 'Color', 'green');
                    hold(app.datosmth, "off");


                end
                
                app.mydata = data;
                name = "dataset_" + app.nom + ".mat";
                app.elapsed_time = toc;
                data.elapsed_time = app.elapsed_time;
                
                save(name, "data");

                app.TextArea.Value = "== Toma de datos finalizada! ==" + newline + newline + "Numero de muestras = " + num2str(app.n) + newline +  "Presion maxima = " + num2str(app.pmax) + " ms" + newline + "Presion minima = " + num2str(app.pmin)  + " ms" + newline + "Nombre: " + "dataset_" + app.nom + ".mat"+ newline + "P. presostato = " + num2str(app.p_presostato) + " Mpa" + newline + "T. Amb = " + num2str(app.temp_amb) + "ºC" + newline + newline + newline + "Tiempo transcurrido: " + num2str(app.elapsed_time) + "s";
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopped = 1;
            app.StartButton.BackgroundColor = 'gray';
            app.TextArea.Value = "STOPPED";
        end

        % Value changed function: RedKnob
        function RedKnobValueChanged(app, event)
            value = app.RedKnob.Value;
            app.EditR.Value = value;
            writeline(app.mi_arduino, "v,1," + num2str(value));
        end

        % Value changed function: GreenKnob
        function GreenKnobValueChanged(app, event)
            value = app.GreenKnob.Value;
            app.EditG.Value = value;
            writeline(app.mi_arduino, "v,2," + num2str(value));
        end

        % Value changed function: BlueKnob
        function BlueKnobValueChanged(app, event)
            value = app.BlueKnob.Value;
            app.EditB.Value = value;
            writeline(app.mi_arduino, "v,3," + num2str(value));
        end

        % Value changed function: EditR
        function EditRValueChanged(app, event)
            value = app.EditR.Value;
            app.RedKnob.Value = value;
            writeline(app.mi_arduino, "v,1," + num2str(value));
        end

        % Value changed function: EditG
        function EditGValueChanged(app, event)
            value = app.EditG.Value;
            app.GreenKnob.Value = value;
            writeline(app.mi_arduino, "v,2," + num2str(value));
        end

        % Value changed function: EditB
        function EditBValueChanged(app, event)
            value = app.EditB.Value;
            app.BlueKnob.Value = value;
            writeline(app.mi_arduino, "v,3," + num2str(value));
        end

        % Button pushed function: EnviarButton
        function EnviarButtonPushed(app, event)
                   
                   app.justSended = [app.v1.Value app.v2.Value app.v3.Value app.v4.Value app.v5.Value app.v6.Value app.v7.Value app.v8.Value app.v9.Value];
                   str = "w,-1";

                    for i = 1:9
                        writeline(app.mi_arduino, "x," + num2str(i -1 ) + "," + app.justSended(i));
                        str = str + num2str(app.justSended(i)) + ", ";
                        pause(0.2);
                    end

                   for i = 1:9

                       str = str + "," + num2str(app.justSended(i));

                   end

                   app.TextArea.Value = str;
                   writeline(app.mi_arduino, str);


        end

        % Value changed function: S11Knob
        function S11KnobValueChanged(app, event)
            value = app.S11Knob.Value;
            writeline(app.mi_arduino, "x,0," + num2str(value));
        end

        % Value changed function: S12Knob
        function S12KnobValueChanged(app, event)
            value = app.S12Knob.Value;
            writeline(app.mi_arduino, "x,1," + num2str(value));
        end

        % Value changed function: S13Knob
        function S13KnobValueChanged(app, event)
            value = app.S13Knob.Value;
            writeline(app.mi_arduino, "x,2," + num2str(value));
        end

        % Value changed function: S21Knob
        function S21KnobValueChanged(app, event)
            value = app.S21Knob.Value;
            writeline(app.mi_arduino, "x,3," + num2str(value));
        end

        % Value changed function: S22Knob
        function S22KnobValueChanged(app, event)
            value = app.S22Knob.Value;
            writeline(app.mi_arduino, "x,4," + num2str(value));
        end

        % Value changed function: S23Knob
        function S23KnobValueChanged(app, event)
            value = app.S23Knob.Value;
            writeline(app.mi_arduino, "x,5," + num2str(value));
        end

        % Value changed function: S31Knob
        function S31KnobValueChanged(app, event)
            value = app.S31Knob.Value;
            writeline(app.mi_arduino, "x,6," + num2str(value));
        end

        % Value changed function: S32Knob
        function S32KnobValueChanged(app, event)
            value = app.S32Knob.Value;
            writeline(app.mi_arduino, "x,7," + num2str(value));
        end

        % Value changed function: S33Knob
        function S33KnobValueChanged(app, event)
            value = app.S33Knob.Value;
            writeline(app.mi_arduino, "x,8," + num2str(value));
        end

        % Value changed function: Max_rel_pressureEditField
        function Max_rel_pressureEditFieldValueChanged(app, event)
            value = app.Max_rel_pressureEditField.Value;
            writeline(app.mi_arduino, "l," + num2str(value));
        end

        % Value changed function: EFCompensatorEditField
        function EFCompensatorEditFieldValueChanged(app, event)
            value = app.EFCompensatorEditField.Value;
            writeline(app.mi_arduino, "m," + num2str(value));
        end

        % Button pushed function: RearmeButton
        function RearmeButtonPushed(app, event)
            writeline(app.mi_arduino, "R");
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            
            S1 = gamma_(app.Alpha1Knob.Value + 240) * app.R1Knob.Value;
            S2 = gamma_(app.Alpha1Knob.Value + 120) * app.R1Knob.Value;
            S3 = gamma_(app.Alpha1Knob.Value) * app.R1Knob.Value;

            S4 = gamma_(app.Alpha2Knob.Value + 240) * app.R2Knob.Value;
            S5 = gamma_(app.Alpha2Knob.Value + 120) * app.R2Knob.Value;
            S6 = gamma_(app.Alpha2Knob.Value) * app.R2Knob.Value;

            S7 = gamma_(app.Alpha3Knob.Value + 240) * app.R3Knob.Value;
            S8 = gamma_(app.Alpha3Knob.Value + 120) * app.R3Knob.Value;
            S9 = gamma_(app.Alpha3Knob.Value) * app.R3Knob.Value;

            writeline(app.mi_arduino, "x,0," + num2str(S1));
            pause(0.2)
            writeline(app.mi_arduino, "x,1," + num2str(S2));
            pause(0.2)
            writeline(app.mi_arduino, "x,2," + num2str(S3));
            pause(0.2)
            writeline(app.mi_arduino, "x,3," + num2str(S4));
            pause(0.2)
            writeline(app.mi_arduino, "x,4," + num2str(S5));
            pause(0.2)
            writeline(app.mi_arduino, "x,5," + num2str(S6));
            pause(0.2)
            writeline(app.mi_arduino, "x,6," + num2str(S7));
            pause(0.2)
            writeline(app.mi_arduino, "x,7," + num2str(S8));
            pause(0.2)
            writeline(app.mi_arduino, "x,8," + num2str(S9));


        end

        % Button pushed function: ActualizarButton
        function ActualizarButtonPushed(app, event)
           app.datasets_list = dir( "*dataset*.mat" );
           nv = size(app.datasets_list);
           num = nv(1);
           
           list = cellstr(ls("*dataset*.mat"));

           app.DatasetsListBox.Items = list;

           disp(list)
        end

        % Button pushed function: CargarButton
        function CargarButtonPushed(app, event)
            name = string(app.DatasetsListBox.Value);
            disp(name);

            tempX = load(name);
            app.datasets_cargados = datafilter(tempX.data,[-1000, 1000], [-1000, 1000], [-1000, 1000], [-1000, 1000], [-1000, 1000], [-1000 1000]);
            plot3(app.datasets_cargados.outputs(:,1), app.datasets_cargados.outputs(:,2), app.datasets_cargados.outputs(:,3), "o", 'Parent', app.Pos_disponibles);


        end

        % Button pushed function: FiltrarButton
        function FiltrarButtonPushed(app, event)
            app.dataset_filtrado = datafilter(app.datasets_cargados, [app.X_minSlider.Value app.X_maxSlider.Value],[app.Y_minSlider.Value app.y_maxSlider.Value],[app.Z_minSlider.Value app.Z_maxSlider.Value],[app.E1_minSlider.Value app.E1_maxSlider.Value],[app.E2_minSlider.Value app.E2_maxSlider.Value],[app.E3_minSlider.Value app.E3_maxSlider.Value]);
            app.ResultadosEditField.Value = app.dataset_filtrado.nummuestras;
            plot3(app.dataset_filtrado.outputs(:,1), app.dataset_filtrado.outputs(:,2), app.dataset_filtrado.outputs(:,3), "o", 'Parent', app.Pos_filtradas);


        end

        % Button down function: Pos_disponibles
        function Pos_disponiblesButtonDown(app, event)
            % co-ordinates of the current selected point
            Pt = app.Pos_disponibles.CurrentPoint(2,:);

            
            disp(Pt);
        end

        % Callback function
        function CargarpuntosButtonPushed(app, event)
           Pt = app.Pos_disponibles.CurrentPoint(2,:);

        for k = 1:app.dataset_filtrado.nummuestras
        arr = [app.dataset_filtrado.outputs(k,1), app.dataset_filtrado.outputs(k,2), app.dataset_filtrado.outputs(k,3);Pt];
        distArr(k) = pdist(arr,'euclidean');
        end

        [~,idx] = min(distArr);
         
        point = [app.dataset_filtrado.outputs(idx,1), app.dataset_filtrado.outputs(idx,2), app.dataset_filtrado.outputs(idx,3)];

        disp(point);
        end

        % Button pushed function: BuscarButton
        function BuscarButtonPushed(app, event)
            punto = [app.XEditField_2.Value, app.YEditField_2.Value, app.ZEditField.Value];
            [p_enc, ang_enc, ~, app.punto_encontrado] = buscador_posicion(app.dataset_filtrado, punto);
            str = "Punto encontrado: [" + num2str(p_enc(1)) + " " + num2str(p_enc(2)) + " " + num2str(p_enc(3)) + "]" + newline +  "Angulos: [" + num2str(rad2deg(ang_enc(1))) + " " + num2str(rad2deg(ang_enc(1))) + " " + num2str(rad2deg(ang_enc(3))) + "]" + newline + "Indice = " + num2str(app.punto_encontrado) ;
            app.OutputTextArea.Value = str;

        end

        % Button pushed function: AnadirButton
        function AnadirButtonPushed(app, event)
            
            app.number_of_points = app.number_of_points + 1;
            app.puntos_ir(app.number_of_points - 1) = app.punto_encontrado;
            list = string(app.puntos_ir);
            app.ListBox.Items = list;

            hold(app.Pos_disponibles, "on"	);
            punto = app.dataset_filtrado.outputs(app.punto_encontrado, 1:3);
            plot3(punto(1), punto(2), punto(3), 'Color','red', 'Parent',app.Pos_disponibles);


        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            inx = str2num(value);
            punto = app.dataset_filtrado.outputs(inx, 1:3);
            angulos = app.dataset_filtrado.outputs(inx, 4:6);
            presiones = app.dataset_filtrado.inputs(inx, 1:9);

            str1 = "Punto seleccionado: [" + num2str(punto(1)) + " " + num2str(punto(2)) + " " + num2str(punto(3)) + "]";
            str2 = "Angulos: [" + num2str(rad2deg(angulos(1))) + " " + num2str(rad2deg(angulos(2))) + " " + num2str(rad2deg(angulos(3))) + "]";
            str3 = "Presiones: [";
            for k = 1:9
                str3 = str3 + num2str(presiones(k)) + " ";
            end
            str3 = str3 + "]";

            str = str1 + newline + str2 + newline + str3;

            app.TextArea_2.Value = str;
        end

        % Button pushed function: IrButton
        function IrButtonPushed(app, event)
           idx = str2num(app.ListBox.Value);
            millis2send__ = app.dataset_filtrado.inputs(idx, 1:9);
           time = 0.05;
           for j = 1:9
            writeline(app.mi_arduino, "x," + num2str(j -1 ) + "," + millis2send__(j));
          
            pause(time);
           end
        end

        % Button pushed function: DesinflarButton_2
        function DesinflarButton_2Pushed(app, event)
            time = 0.01;
                       for j = 1:9
                        writeline(app.mi_arduino, "x," + num2str(j -1 ) + "," + 0);
                        pause(time);
                       end
        end

        % Value changed function: Modo_manualSwitch
        function Modo_manualSwitchValueChanged(app, event)
            value = app.Modo_manualSwitch.Value;
            
            if value == true
                app.EnviarButton.Enable = true;
                app.GuardarButton.Enable = true;
                app.EnviarButton.Enable = true;
                app.StartButton.Enable = false;
            else
                app.EnviarButton.Enable = false;
                app.GuardarButton.Enable = false;
                app.EnviarButton.Enable = false;
                app.StartButton.Enable = true;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InterfazPrincipal = uitabgroup(app.UIFigure);
            app.ConfigTab = uitab(app.InterfazPrincipal);
            app.ConfigTab.SizeChangedFcn = createCallbackFcn(app, @ConfigTabSizeChanged, true);
            app.ConfigTab.Title = 'Config';
            app.LabelBaudios = uilabel(app.ConfigTab);
            app.LabelBaudios.Text = '';
            app.LucesvisionporcomputadorPanel = uipanel(app.ConfigTab);
            app.LucesvisionporcomputadorPanel.Title = 'Luces vision por computador';
            app.RLampLabel = uilabel(app.LucesvisionporcomputadorPanel);
            app.RLampLabel.Text = 'R';
            app.RLamp = uilamp(app.LucesvisionporcomputadorPanel);
            app.GLampLabel = uilabel(app.LucesvisionporcomputadorPanel);
            app.GLampLabel.Text = 'G';
            app.GLamp = uilamp(app.LucesvisionporcomputadorPanel);
            app.BLampLabel = uilabel(app.LucesvisionporcomputadorPanel);
            app.BLampLabel.Text = 'B';
            app.BLamp = uilamp(app.LucesvisionporcomputadorPanel);
            app.RedKnobLabel = uilabel(app.LucesvisionporcomputadorPanel);
            app.RedKnobLabel.Text = 'Red';
            app.RedKnob = uiknob(app.LucesvisionporcomputadorPanel, 'continuous');
            app.RedKnob.Limits = [-1 255];
            app.RedKnob.ValueChangedFcn = createCallbackFcn(app, @RedKnobValueChanged, true);
            app.BlueKnobLabel = uilabel(app.LucesvisionporcomputadorPanel);
            app.BlueKnobLabel.Text = 'Blue';
            app.BlueKnob = uiknob(app.LucesvisionporcomputadorPanel, 'continuous');
            app.BlueKnob.Limits = [-1 255];
            app.BlueKnob.ValueChangedFcn = createCallbackFcn(app, @BlueKnobValueChanged, true);
            app.GreenKnobLabel = uilabel(app.LucesvisionporcomputadorPanel);
            app.GreenKnobLabel.Text = 'Green';
            app.GreenKnob = uiknob(app.LucesvisionporcomputadorPanel, 'continuous');
            app.GreenKnob.Limits = [-1 255];
            app.GreenKnob.ValueChangedFcn = createCallbackFcn(app, @GreenKnobValueChanged, true);
            app.Label = uilabel(app.LucesvisionporcomputadorPanel);
            app.Label.Text = '';
            app.EditR = uieditfield(app.LucesvisionporcomputadorPanel, 'numeric');
            app.EditR.ValueChangedFcn = createCallbackFcn(app, @EditRValueChanged, true);
            app.EditField_2Label = uilabel(app.LucesvisionporcomputadorPanel);
            app.EditField_2Label.Text = '';
            app.EditG = uieditfield(app.LucesvisionporcomputadorPanel, 'numeric');
            app.EditG.ValueChangedFcn = createCallbackFcn(app, @EditGValueChanged, true);
            app.EditField_3Label = uilabel(app.LucesvisionporcomputadorPanel);
            app.EditField_3Label.Text = '';
            app.EditB = uieditfield(app.LucesvisionporcomputadorPanel, 'numeric');
            app.EditB.ValueChangedFcn = createCallbackFcn(app, @EditBValueChanged, true);
            app.ConexionseriePanel = uipanel(app.ConfigTab);
            app.ConexionseriePanel.Title = 'Conexion serie';
            app.GridLayout4 = uigridlayout(app.ConexionseriePanel);
            app.NoconectadoLampLabel = uilabel(app.GridLayout4);
            app.NoconectadoLampLabel.Text = 'No conectado';
            app.NoconectadoLamp = uilamp(app.GridLayout4);
            app.BAUDIOSLabel = uilabel(app.GridLayout4);
            app.BAUDIOSLabel.Text = 'BAUDIOS';
            app.PUERTOLabel = uilabel(app.GridLayout4);
            app.PUERTOLabel.Text = 'PUERTO: ';
            app.ConfigButton = uibutton(app.GridLayout4, 'push');
            app.ConfigButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigButtonPushed, true);
            app.ConfigButton.Text = 'Config';
            app.ConnectButton = uibutton(app.GridLayout4, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.OtrosparametrosPanel = uipanel(app.ConfigTab);
            app.OtrosparametrosPanel.Title = 'Otros parametros';
            app.GridLayout5 = uigridlayout(app.OtrosparametrosPanel);
            app.EFCompensatorEditFieldLabel = uilabel(app.GridLayout5);
            app.EFCompensatorEditFieldLabel.Text = 'EF Compensator';
            app.EFCompensatorEditField = uieditfield(app.GridLayout5, 'numeric');
            app.EFCompensatorEditField.ValueChangedFcn = createCallbackFcn(app, @EFCompensatorEditFieldValueChanged, true);
            app.EFCompensatorEditField.Value = 1.2;
            app.Max_rel_pressureEditFieldLabel = uilabel(app.GridLayout5);
            app.Max_rel_pressureEditFieldLabel.Text = 'Max_rel_pressure';
            app.Max_rel_pressureEditField = uieditfield(app.GridLayout5, 'numeric');
            app.Max_rel_pressureEditField.ValueChangedFcn = createCallbackFcn(app, @Max_rel_pressureEditFieldValueChanged, true);
            app.Max_rel_pressureEditField.Value = 100;
            app.RearmeButton = uibutton(app.ConfigTab, 'push');
            app.RearmeButton.ButtonPushedFcn = createCallbackFcn(app, @RearmeButtonPushed, true);
            app.RearmeButton.Text = 'Rearme';
            app.Image4 = uiimage(app.ConfigTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'cam_xz6.jpeg');
            app.ClimbIndicatorLabel = uilabel(app.ConfigTab);
            app.ClimbIndicatorLabel.Text = 'ClimbIndicator';
            app.ClimbIndicator = uiaeroclimb(app.ConfigTab);
            app.ArtificialHorizonLabel = uilabel(app.ConfigTab);
            app.ArtificialHorizonLabel.Text = 'ArtificialHorizon';
            app.ArtificialHorizon = uiaerohorizon(app.ConfigTab);
            app.HeadingIndicatorLabel = uilabel(app.ConfigTab);
            app.HeadingIndicatorLabel.Text = 'HeadingIndicator';
            app.HeadingIndicator = uiaeroheading(app.ConfigTab);
            app.VisionporcomputadorTab = uitab(app.InterfazPrincipal);
            app.VisionporcomputadorTab.Title = 'Vision por computador';
            app.CamarasPanel = uipanel(app.VisionporcomputadorTab);
            app.CamarasPanel.Title = 'Camaras';
            app.GridLayout7 = uigridlayout(app.CamarasPanel);
            app.UIAxes_yz = uiaxes(app.GridLayout7);
            title(app.UIAxes_yz, 'CAM_YZ')
            xlabel(app.UIAxes_yz, 'X')
            ylabel(app.UIAxes_yz, 'Y')
            zlabel(app.UIAxes_yz, 'Z')
            app.UIAxes_xz = uiaxes(app.GridLayout7);
            title(app.UIAxes_xz, 'CAM_XZ')
            xlabel(app.UIAxes_xz, 'X')
            ylabel(app.UIAxes_xz, 'Y')
            zlabel(app.UIAxes_xz, 'Z')
            app.BotoneraPanel = uipanel(app.VisionporcomputadorTab);
            app.BotoneraPanel.Title = 'Botonera';
            app.GridLayout8 = uigridlayout(app.BotoneraPanel);
            app.connect = uibutton(app.GridLayout8, 'push');
            app.connect.ButtonPushedFcn = createCallbackFcn(app, @connectButtonPushed, true);
            app.connect.Text = 'Connect';
            app.CapturarposicionButton = uibutton(app.GridLayout8, 'push');
            app.CapturarposicionButton.ButtonPushedFcn = createCallbackFcn(app, @CapturarposicionButtonPushed, true);
            app.CapturarposicionButton.Text = 'Capturar posicion';
            app.zeroButton = uibutton(app.GridLayout8, 'push');
            app.zeroButton.ButtonPushedFcn = createCallbackFcn(app, @zeroButtonPushed, true);
            app.zeroButton.Text = 'zero';
            app.Z_offsetEditFieldLabel = uilabel(app.GridLayout8);
            app.Z_offsetEditFieldLabel.Text = 'Z_offset';
            app.Z_offsetEditField = uieditfield(app.GridLayout8, 'numeric');
            app.setButton = uibutton(app.GridLayout8, 'push');
            app.setButton.ButtonPushedFcn = createCallbackFcn(app, @setButtonPushed, true);
            app.setButton.Text = 'set';
            app.PosicionText = uitextarea(app.VisionporcomputadorTab);
            app.RecogidadedatosTab = uitab(app.InterfazPrincipal);
            app.RecogidadedatosTab.Title = 'Recogida de datos';
            app.SetButton = uibutton(app.RecogidadedatosTab, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.StartButton = uibutton(app.RecogidadedatosTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.RecogidadedatosTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.TextAreaLabel = uilabel(app.RecogidadedatosTab);
            app.TextAreaLabel.Text = '';
            app.TextArea = uitextarea(app.RecogidadedatosTab);
            app.ManualPanel = uipanel(app.RecogidadedatosTab);
            app.ManualPanel.Title = 'Manual';
            app.V1EditFieldLabel = uilabel(app.ManualPanel);
            app.V1EditFieldLabel.Text = 'V1';
            app.v1 = uieditfield(app.ManualPanel, 'numeric');
            app.V2EditFieldLabel = uilabel(app.ManualPanel);
            app.V2EditFieldLabel.Text = 'V2';
            app.v2 = uieditfield(app.ManualPanel, 'numeric');
            app.V3EditFieldLabel = uilabel(app.ManualPanel);
            app.V3EditFieldLabel.Text = 'V3';
            app.v3 = uieditfield(app.ManualPanel, 'numeric');
            app.v4 = uieditfield(app.ManualPanel, 'numeric');
            app.V1EditField_2Label_2 = uilabel(app.ManualPanel);
            app.V1EditField_2Label_2.Text = 'V5';
            app.v5 = uieditfield(app.ManualPanel, 'numeric');
            app.V1EditField_6 = uieditfield(app.ManualPanel, 'numeric');
            app.V1EditField_3Label_2 = uilabel(app.ManualPanel);
            app.V1EditField_3Label_2.Text = 'V6';
            app.v6 = uieditfield(app.ManualPanel, 'numeric');
            app.v7 = uieditfield(app.ManualPanel, 'numeric');
            app.v8 = uieditfield(app.ManualPanel, 'numeric');
            app.v9 = uieditfield(app.ManualPanel, 'numeric');
            app.EnviarButton = uibutton(app.ManualPanel, 'push');
            app.EnviarButton.ButtonPushedFcn = createCallbackFcn(app, @EnviarButtonPushed, true);
            app.EnviarButton.Text = 'Enviar';
            app.TomarposicionButton = uibutton(app.ManualPanel, 'push');
            app.TomarposicionButton.Text = 'Tomar posicion';
            app.DesinflarButton = uibutton(app.ManualPanel, 'push');
            app.DesinflarButton.Text = 'Desinflar';
            app.GuardarButton = uibutton(app.ManualPanel, 'push');
            app.GuardarButton.Text = 'Guardar';
            app.VisionporcomputadorPanel = uipanel(app.RecogidadedatosTab);
            app.VisionporcomputadorPanel.Title = 'Vision por computador';
            app.Label_2 = uilabel(app.VisionporcomputadorPanel);
            app.Label_2.Text = '';
            app.Lamp_2 = uilamp(app.VisionporcomputadorPanel);
            app.GridLayout = uigridlayout(app.VisionporcomputadorPanel);
            app.datosxz = uiaxes(app.GridLayout);
            title(app.datosxz, 'XZ (USB cam)')
            xlabel(app.datosxz, 'X')
            ylabel(app.datosxz, 'Y')
            zlabel(app.datosxz, 'Z')
            app.datosyz = uiaxes(app.GridLayout);
            title(app.datosyz, 'YZ (Logitech)')
            xlabel(app.datosyz, 'X')
            ylabel(app.datosyz, 'Y')
            zlabel(app.datosyz, 'Z')
            app.CoordenadashomogeneasPanel = uipanel(app.RecogidadedatosTab);
            app.CoordenadashomogeneasPanel.Title = 'Coordenadas homogeneas';
            app.GridLayout2 = uigridlayout(app.CoordenadashomogeneasPanel);
            app.datosmth = uiaxes(app.GridLayout2);
            title(app.datosmth, 'mth')
            xlabel(app.datosmth, 'X')
            ylabel(app.datosmth, 'Y')
            zlabel(app.datosmth, 'Z')
            app.DatosdeentradaPanel = uipanel(app.RecogidadedatosTab);
            app.DatosdeentradaPanel.Title = 'Datos de entrada';
            app.GridLayout9 = uigridlayout(app.DatosdeentradaPanel);
            app.Pmin_S1EditFieldLabel = uilabel(app.GridLayout9);
            app.Pmin_S1EditFieldLabel.Text = 'Pmin_S1';
            app.Pmin_S1 = uieditfield(app.GridLayout9, 'numeric');
            app.Pmax_S1EditField_2Label = uilabel(app.GridLayout9);
            app.Pmax_S1EditField_2Label.Text = 'Pmax_S1';
            app.Pmax_S1 = uieditfield(app.GridLayout9, 'numeric');
            app.Pmin_S2EditFieldLabel = uilabel(app.GridLayout9);
            app.Pmin_S2EditFieldLabel.Text = 'Pmin_S2';
            app.Pmin_S2 = uieditfield(app.GridLayout9, 'numeric');
            app.Pmax_S2EditFieldLabel = uilabel(app.GridLayout9);
            app.Pmax_S2EditFieldLabel.Text = 'Pmax_S2';
            app.Pmax_S2 = uieditfield(app.GridLayout9, 'numeric');
            app.Pmin_S3EditFieldLabel = uilabel(app.GridLayout9);
            app.Pmin_S3EditFieldLabel.Text = 'Pmin_S3';
            app.Pmin_S3 = uieditfield(app.GridLayout9, 'numeric');
            app.Pmax_S3EditFieldLabel = uilabel(app.GridLayout9);
            app.Pmax_S3EditFieldLabel.Text = 'Pmax_S3';
            app.Pmax_S3 = uieditfield(app.GridLayout9, 'numeric');
            app.NumerodemuestrasEditFieldLabel = uilabel(app.GridLayout9);
            app.NumerodemuestrasEditFieldLabel.Text = 'Numero de muestras';
            app.NumerodemuestrasEditField = uieditfield(app.GridLayout9, 'numeric');
            app.PpresostatoMpaEditFieldLabel = uilabel(app.GridLayout9);
            app.PpresostatoMpaEditFieldLabel.Text = 'P. presostato (Mpa)';
            app.PpresostatoMpaEditField = uieditfield(app.GridLayout9, 'numeric');
            app.TempambienteEditFieldLabel = uilabel(app.GridLayout9);
            app.TempambienteEditFieldLabel.Text = 'Temp. ambiente';
            app.TempambienteEditField = uieditfield(app.GridLayout9, 'numeric');
            app.NombrevariableEditFieldLabel = uilabel(app.GridLayout9);
            app.NombrevariableEditFieldLabel.Text = 'Nombre variable';
            app.NombrevariableEditField = uieditfield(app.GridLayout9, 'text');
            app.ControlporcentualSwitchLabel = uilabel(app.RecogidadedatosTab);
            app.ControlporcentualSwitchLabel.Text = 'Control porcentual';
            app.ControlporcentualSwitch = uiswitch(app.RecogidadedatosTab, 'slider');
            app.Modo_manualSwitchLabel = uilabel(app.RecogidadedatosTab);
            app.Modo_manualSwitchLabel.Text = 'Modo_manual';
            app.Modo_manualSwitch = uiswitch(app.RecogidadedatosTab, 'slider');
            app.Modo_manualSwitch.ValueChangedFcn = createCallbackFcn(app, @Modo_manualSwitchValueChanged, true);
            app.ModomanualTab = uitab(app.InterfazPrincipal);
            app.ModomanualTab.Title = 'Modo manual';
            app.Tabsmodomanual = uitabgroup(app.ModomanualTab);
            app.ControlporcentualTab = uitab(app.Tabsmodomanual);
            app.ControlporcentualTab.Title = 'Control porcentual';
            app.GradosdelibertadPanel = uipanel(app.ControlporcentualTab);
            app.GradosdelibertadPanel.Title = 'Grados de libertad';
            app.GridLayout3 = uigridlayout(app.GradosdelibertadPanel);
            app.S11KnobLabel = uilabel(app.GridLayout3);
            app.S11KnobLabel.Text = 'S11';
            app.S11Knob = uiknob(app.GridLayout3, 'continuous');
            app.S11Knob.ValueChangedFcn = createCallbackFcn(app, @S11KnobValueChanged, true);
            app.S12KnobLabel = uilabel(app.GridLayout3);
            app.S12KnobLabel.Text = 'S12';
            app.S12Knob = uiknob(app.GridLayout3, 'continuous');
            app.S12Knob.ValueChangedFcn = createCallbackFcn(app, @S12KnobValueChanged, true);
            app.S21KnobLabel = uilabel(app.GridLayout3);
            app.S21KnobLabel.Text = 'S21';
            app.S21Knob = uiknob(app.GridLayout3, 'continuous');
            app.S21Knob.ValueChangedFcn = createCallbackFcn(app, @S21KnobValueChanged, true);
            app.S22KnobLabel = uilabel(app.GridLayout3);
            app.S22KnobLabel.Text = 'S22';
            app.S22Knob = uiknob(app.GridLayout3, 'continuous');
            app.S22Knob.ValueChangedFcn = createCallbackFcn(app, @S22KnobValueChanged, true);
            app.S13KnobLabel = uilabel(app.GridLayout3);
            app.S13KnobLabel.Text = 'S13';
            app.S13Knob = uiknob(app.GridLayout3, 'continuous');
            app.S13Knob.ValueChangedFcn = createCallbackFcn(app, @S13KnobValueChanged, true);
            app.S23KnobLabel = uilabel(app.GridLayout3);
            app.S23KnobLabel.Text = 'S23';
            app.S23Knob = uiknob(app.GridLayout3, 'continuous');
            app.S23Knob.ValueChangedFcn = createCallbackFcn(app, @S23KnobValueChanged, true);
            app.S31KnobLabel = uilabel(app.GridLayout3);
            app.S31KnobLabel.Text = 'S31';
            app.S31Knob = uiknob(app.GridLayout3, 'continuous');
            app.S31Knob.ValueChangedFcn = createCallbackFcn(app, @S31KnobValueChanged, true);
            app.S32KnobLabel = uilabel(app.GridLayout3);
            app.S32KnobLabel.Text = 'S32';
            app.S32Knob = uiknob(app.GridLayout3, 'continuous');
            app.S32Knob.ValueChangedFcn = createCallbackFcn(app, @S32KnobValueChanged, true);
            app.S33KnobLabel = uilabel(app.GridLayout3);
            app.S33KnobLabel.Text = 'S33';
            app.S33Knob = uiknob(app.GridLayout3, 'continuous');
            app.S33Knob.ValueChangedFcn = createCallbackFcn(app, @S33KnobValueChanged, true);
            app.Image = uiimage(app.GridLayout3);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Banco de actuacion', 'compresor.jpg');
            app.Image2 = uiimage(app.GridLayout3);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Banco de actuacion', 'valvulas22.jpg');
            app.Image3 = uiimage(app.GridLayout3);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Banco de actuacion', 'valvulas32.jpg');
            app.ControlRadioanguloTab = uitab(app.Tabsmodomanual);
            app.ControlRadioanguloTab.Title = 'Control Radio angulo';
            app.ControlesPanel = uipanel(app.ControlRadioanguloTab);
            app.ControlesPanel.Title = 'Controles';
            app.GridLayout6 = uigridlayout(app.ControlesPanel);
            app.R1KnobLabel = uilabel(app.GridLayout6);
            app.R1KnobLabel.Text = 'R1';
            app.R1Knob = uiknob(app.GridLayout6, 'continuous');
            app.Alpha1KnobLabel = uilabel(app.GridLayout6);
            app.Alpha1KnobLabel.Text = 'Alpha 1';
            app.Alpha1Knob = uiknob(app.GridLayout6, 'continuous');
            app.Alpha1Knob.Limits = [0 360];
            app.R2KnobLabel = uilabel(app.GridLayout6);
            app.R2KnobLabel.Text = 'R2';
            app.R2Knob = uiknob(app.GridLayout6, 'continuous');
            app.Alpha2KnobLabel = uilabel(app.GridLayout6);
            app.Alpha2KnobLabel.Text = 'Alpha 2';
            app.Alpha2Knob = uiknob(app.GridLayout6, 'continuous');
            app.Alpha2Knob.Limits = [0 360];
            app.R3KnobLabel = uilabel(app.GridLayout6);
            app.R3KnobLabel.Text = 'R3';
            app.R3Knob = uiknob(app.GridLayout6, 'continuous');
            app.Alpha3KnobLabel = uilabel(app.GridLayout6);
            app.Alpha3KnobLabel.Text = 'Alpha 3';
            app.Alpha3Knob = uiknob(app.GridLayout6, 'continuous');
            app.Alpha3Knob.Limits = [0 360];
            app.SendButton = uibutton(app.ControlRadioanguloTab, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.Segmento0Tab = uitab(app.Tabsmodomanual);
            app.Segmento0Tab.Title = 'Segmento 0';
            app.V1Gauge_2Label = uilabel(app.Segmento0Tab);
            app.V1Gauge_2Label.Text = 'V1';
            app.GaugeV1 = uigauge(app.Segmento0Tab, 'circular');
            app.GaugeV1.Limits = [-5000 5000];
            app.V2Gauge_4Label = uilabel(app.Segmento0Tab);
            app.V2Gauge_4Label.Text = 'V2';
            app.GaugeV2 = uigauge(app.Segmento0Tab, 'circular');
            app.GaugeV2.Limits = [-5000 5000];
            app.V2Label = uilabel(app.Segmento0Tab);
            app.V2Label.Text = 'V3';
            app.GaugeV3 = uigauge(app.Segmento0Tab, 'circular');
            app.GaugeV3.Limits = [-5000 5000];
            app.sendV1 = uibutton(app.Segmento0Tab, 'push');
            app.sendV1.ButtonPushedFcn = createCallbackFcn(app, @sendV1ButtonPushed, true);
            app.sendV1.Text = 'send';
            app.sendV3 = uibutton(app.Segmento0Tab, 'push');
            app.sendV3.ButtonPushedFcn = createCallbackFcn(app, @sendV3ButtonPushed, true);
            app.sendV3.Text = 'send';
            app.sendV2 = uibutton(app.Segmento0Tab, 'push');
            app.sendV2.ButtonPushedFcn = createCallbackFcn(app, @sendV2ButtonPushed, true);
            app.sendV2.Text = 'send';
            app.Segmento1Tab = uitab(app.Tabsmodomanual);
            app.Segmento1Tab.Title = 'Segmento 1';
            app.sendV4 = uibutton(app.Segmento1Tab, 'push');
            app.sendV4.ButtonPushedFcn = createCallbackFcn(app, @sendV4ButtonPushed, true);
            app.sendV4.Text = 'send';
            app.sendV6 = uibutton(app.Segmento1Tab, 'push');
            app.sendV6.ButtonPushedFcn = createCallbackFcn(app, @sendV6ButtonPushed, true);
            app.sendV6.Text = 'send';
            app.sendV5 = uibutton(app.Segmento1Tab, 'push');
            app.sendV5.ButtonPushedFcn = createCallbackFcn(app, @sendV5ButtonPushed, true);
            app.sendV5.Text = 'send';
            app.V4Gauge_2Label = uilabel(app.Segmento1Tab);
            app.V4Gauge_2Label.Text = 'V4';
            app.GaugeV4 = uigauge(app.Segmento1Tab, 'circular');
            app.GaugeV4.Limits = [-5000 5000];
            app.V5GaugeLabel = uilabel(app.Segmento1Tab);
            app.V5GaugeLabel.Text = 'V5';
            app.GaugeV5 = uigauge(app.Segmento1Tab, 'circular');
            app.GaugeV5.Limits = [-5000 5000];
            app.V0Gauge_3Label_2 = uilabel(app.Segmento1Tab);
            app.V0Gauge_3Label_2.Text = 'V6';
            app.GaugeV6 = uigauge(app.Segmento1Tab, 'circular');
            app.GaugeV6.Limits = [-5000 5000];
            app.Segmento2Tab = uitab(app.Tabsmodomanual);
            app.Segmento2Tab.Title = 'Segmento 2';
            app.sendV7 = uibutton(app.Segmento2Tab, 'push');
            app.sendV7.ButtonPushedFcn = createCallbackFcn(app, @sendV7ButtonPushed, true);
            app.sendV7.Text = 'send';
            app.sendV9 = uibutton(app.Segmento2Tab, 'push');
            app.sendV9.ButtonPushedFcn = createCallbackFcn(app, @sendV9ButtonPushed, true);
            app.sendV9.Text = 'send';
            app.sendV8 = uibutton(app.Segmento2Tab, 'push');
            app.sendV8.ButtonPushedFcn = createCallbackFcn(app, @sendV8ButtonPushed, true);
            app.sendV8.Text = 'send';
            app.V0Gauge_3Label_3 = uilabel(app.Segmento2Tab);
            app.V0Gauge_3Label_3.Text = 'V7';
            app.GaugeV7 = uigauge(app.Segmento2Tab, 'circular');
            app.GaugeV7.Limits = [-5000 5000];
            app.V8GaugeLabel = uilabel(app.Segmento2Tab);
            app.V8GaugeLabel.Text = 'V8';
            app.GaugeV8 = uigauge(app.Segmento2Tab, 'circular');
            app.GaugeV8.Limits = [-5000 5000];
            app.V0Gauge_3Label_4 = uilabel(app.Segmento2Tab);
            app.V0Gauge_3Label_4.Text = 'V9';
            app.GaugeV9 = uigauge(app.Segmento2Tab, 'circular');
            app.GaugeV9.Limits = [-5000 5000];
            app.Tab_2 = uitab(app.Tabsmodomanual);
            app.Tab_2.Title = 'Tab';
            app.PresionaenviarCuandosecontrolamanualmenteSliderLabel = uilabel(app.ModomanualTab);
            app.PresionaenviarCuandosecontrolamanualmenteSliderLabel.Text = ' Presion a enviar (Cuando se controla manualmente)';
            app.PresionaenviarCuandosecontrolamanualmenteSlider = uislider(app.ModomanualTab);
            app.PresionaenviarCuandosecontrolamanualmenteSlider.Limits = [-1000 1000];
            app.PresionaenviarCuandosecontrolamanualmenteSlider.ValueChangedFcn = createCallbackFcn(app, @PresionaenviarCuandosecontrolamanualmenteSliderValueChanged, true);
            app.Image5 = uiimage(app.ModomanualTab);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Banco de actuacion', 'Banco_adrian.jpg');
            app.Tab = uitab(app.InterfazPrincipal);
            app.TabGroup = uitabgroup(app.Tab);
            app.AjustesTab = uitab(app.TabGroup);
            app.AjustesTab.Title = 'Ajustes';
            app.CapturadedatosTab = uitab(app.TabGroup);
            app.CapturadedatosTab.Title = 'Captura de datos';
            app.ControlmediantetablaTab = uitab(app.InterfazPrincipal);
            app.ControlmediantetablaTab.Title = 'Control mediante tabla';
            app.VisualizaciondeposicionesPanel = uipanel(app.ControlmediantetablaTab);
            app.VisualizaciondeposicionesPanel.Title = 'Visualizacion de posiciones';
            app.GridLayout10 = uigridlayout(app.VisualizaciondeposicionesPanel);
            app.Pos_disponibles = uiaxes(app.GridLayout10);
            title(app.Pos_disponibles, 'Title')
            xlabel(app.Pos_disponibles, 'X')
            ylabel(app.Pos_disponibles, 'Y')
            zlabel(app.Pos_disponibles, 'Z')
            app.Pos_disponibles.ButtonDownFcn = createCallbackFcn(app, @Pos_disponiblesButtonDown, true);
            app.Pos_filtradas = uiaxes(app.GridLayout10);
            title(app.Pos_filtradas, 'Title')
            xlabel(app.Pos_filtradas, 'X')
            ylabel(app.Pos_filtradas, 'Y')
            zlabel(app.Pos_filtradas, 'Z')
            app.TabGroup2 = uitabgroup(app.ControlmediantetablaTab);
            app.CargardatasetTab = uitab(app.TabGroup2);
            app.CargardatasetTab.Title = 'Cargar dataset';
            app.cargardatasetsPanel = uipanel(app.CargardatasetTab);
            app.cargardatasetsPanel.Title = 'cargar datasets';
            app.GridLayout11 = uigridlayout(app.cargardatasetsPanel);
            app.DatasetsListBox = uilistbox(app.GridLayout11);
            app.DatasetsListBox.Multiselect = 'on';
            app.DatasetsListBox.Value = {'Item 1'};
            app.ActualizarButton = uibutton(app.GridLayout11, 'push');
            app.ActualizarButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarButtonPushed, true);
            app.ActualizarButton.Text = 'Actualizar';
            app.CargarButton = uibutton(app.GridLayout11, 'push');
            app.CargarButton.ButtonPushedFcn = createCallbackFcn(app, @CargarButtonPushed, true);
            app.CargarButton.Text = 'Cargar';
            app.FiltroTab = uitab(app.TabGroup2);
            app.FiltroTab.Title = 'Filtro';
            app.GridLayout13 = uigridlayout(app.FiltroTab);
            app.X_minSliderLabel = uilabel(app.GridLayout13);
            app.X_minSliderLabel.Text = 'X_min';
            app.X_minSlider = uislider(app.GridLayout13);
            app.X_minSlider.Limits = [-300 300];
            app.X_minSlider.Value = -300;
            app.X_maxSliderLabel = uilabel(app.GridLayout13);
            app.X_maxSliderLabel.Text = 'X_max';
            app.X_maxSlider = uislider(app.GridLayout13);
            app.X_maxSlider.Limits = [-300 300];
            app.X_maxSlider.Value = 300;
            app.Y_minSliderLabel = uilabel(app.GridLayout13);
            app.Y_minSliderLabel.Text = 'Y_min';
            app.Y_minSlider = uislider(app.GridLayout13);
            app.Y_minSlider.Limits = [-300 300];
            app.Y_minSlider.Value = -300;
            app.y_maxSliderLabel = uilabel(app.GridLayout13);
            app.y_maxSliderLabel.Text = 'y_max';
            app.y_maxSlider = uislider(app.GridLayout13);
            app.y_maxSlider.Limits = [-300 300];
            app.y_maxSlider.Value = 300;
            app.Z_minSliderLabel = uilabel(app.GridLayout13);
            app.Z_minSliderLabel.Text = 'Z_min';
            app.Z_minSlider = uislider(app.GridLayout13);
            app.Z_minSlider.Limits = [-50 50];
            app.Z_minSlider.Value = -50;
            app.Z_maxSliderLabel = uilabel(app.GridLayout13);
            app.Z_maxSliderLabel.Text = 'Z_max';
            app.Z_maxSlider = uislider(app.GridLayout13);
            app.Z_maxSlider.Limits = [-50 50];
            app.Z_maxSlider.Value = 50;
            app.E1_minSliderLabel = uilabel(app.GridLayout13);
            app.E1_minSliderLabel.Text = 'E1_min';
            app.E1_minSlider = uislider(app.GridLayout13);
            app.E1_minSlider.Limits = [-2 2];
            app.E1_minSlider.Value = -2;
            app.E1_maxSliderLabel = uilabel(app.GridLayout13);
            app.E1_maxSliderLabel.Text = 'E1_max';
            app.E1_maxSlider = uislider(app.GridLayout13);
            app.E1_maxSlider.Limits = [-2 2];
            app.E1_maxSlider.Value = 2;
            app.E2_minSliderLabel = uilabel(app.GridLayout13);
            app.E2_minSliderLabel.Text = 'E2_min';
            app.E2_minSlider = uislider(app.GridLayout13);
            app.E2_minSlider.Limits = [-2 2];
            app.E2_minSlider.Value = -2;
            app.E3_minSliderLabel = uilabel(app.GridLayout13);
            app.E3_minSliderLabel.Text = 'E3_min';
            app.E3_minSlider = uislider(app.GridLayout13);
            app.E3_minSlider.Limits = [-2 2];
            app.E3_minSlider.Value = -2;
            app.E2_maxSliderLabel = uilabel(app.GridLayout13);
            app.E2_maxSliderLabel.Text = 'E2_max';
            app.E2_maxSlider = uislider(app.GridLayout13);
            app.E2_maxSlider.Limits = [-2 2];
            app.E2_maxSlider.Value = 2;
            app.E3_maxSlider_2Label = uilabel(app.GridLayout13);
            app.E3_maxSlider_2Label.Text = 'E3_max';
            app.E3_maxSlider = uislider(app.GridLayout13);
            app.E3_maxSlider.Limits = [-2 2];
            app.E3_maxSlider.Value = 2;
            app.FiltrarButton = uibutton(app.GridLayout13, 'push');
            app.FiltrarButton.ButtonPushedFcn = createCallbackFcn(app, @FiltrarButtonPushed, true);
            app.FiltrarButton.Text = 'Filtrar';
            app.ResultadosEditFieldLabel = uilabel(app.GridLayout13);
            app.ResultadosEditFieldLabel.Text = 'Resultados';
            app.ResultadosEditField = uieditfield(app.GridLayout13, 'numeric');
            app.BuscadorTab = uitab(app.TabGroup2);
            app.BuscadorTab.Title = 'Buscador';
            app.BuscadordepuntosPanel = uipanel(app.BuscadorTab);
            app.BuscadordepuntosPanel.Title = 'Buscador de puntos';
            app.GridLayout15 = uigridlayout(app.BuscadordepuntosPanel);
            app.XEditField_2Label = uilabel(app.GridLayout15);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.GridLayout15, 'numeric');
            app.YEditField_2Label = uilabel(app.GridLayout15);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.GridLayout15, 'numeric');
            app.ZEditField_2Label = uilabel(app.GridLayout15);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.GridLayout15, 'numeric');
            app.E1KnobLabel = uilabel(app.GridLayout15);
            app.E1KnobLabel.Text = 'E1';
            app.E1Knob = uiknob(app.GridLayout15, 'continuous');
            app.E1Knob.Limits = [0 360];
            app.E2KnobLabel = uilabel(app.GridLayout15);
            app.E2KnobLabel.Text = 'E2';
            app.E2Knob = uiknob(app.GridLayout15, 'continuous');
            app.E2Knob.Limits = [0 360];
            app.E3KnobLabel = uilabel(app.GridLayout15);
            app.E3KnobLabel.Text = 'E3';
            app.E3Knob = uiknob(app.GridLayout15, 'continuous');
            app.E3Knob.Limits = [0 360];
            app.AnadirButton = uibutton(app.GridLayout15, 'push');
            app.AnadirButton.ButtonPushedFcn = createCallbackFcn(app, @AnadirButtonPushed, true);
            app.AnadirButton.Text = 'Anadir';
            app.BuscarButton = uibutton(app.GridLayout15, 'push');
            app.BuscarButton.ButtonPushedFcn = createCallbackFcn(app, @BuscarButtonPushed, true);
            app.BuscarButton.Text = 'Buscar';
            app.OutputTextArea = uitextarea(app.BuscadorTab);
            app.ControlTab = uitab(app.TabGroup2);
            app.ControlTab.Title = 'Control';
            app.PuntosPanel = uipanel(app.ControlTab);
            app.PuntosPanel.Title = 'Puntos ';
            app.GridLayout16 = uigridlayout(app.PuntosPanel);
            app.ListBoxLabel = uilabel(app.GridLayout16);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.GridLayout16);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.TextArea_2Label = uilabel(app.GridLayout16);
            app.TextArea_2Label.Text = 'Text Area';
            app.TextArea_2 = uitextarea(app.GridLayout16);
            app.IrButton = uibutton(app.GridLayout16, 'push');
            app.IrButton.ButtonPushedFcn = createCallbackFcn(app, @IrButtonPushed, true);
            app.IrButton.Text = 'Ir';
            app.DesinflarButton_2 = uibutton(app.GridLayout16, 'push');
            app.DesinflarButton_2.ButtonPushedFcn = createCallbackFcn(app, @DesinflarButton_2Pushed, true);
            app.DesinflarButton_2.Text = 'Desinflar';
            app.ControlmedianteIATab = uitab(app.InterfazPrincipal);
            app.ControlmedianteIATab.Title = 'Control mediante IA';
            app.TabGroup3 = uitabgroup(app.ControlmedianteIATab);
            app.aunpuntoTab = uitab(app.TabGroup3);
            app.aunpuntoTab.Title = 'a un punto';
            app.PosicioninicialPanel = uipanel(app.aunpuntoTab);
            app.PosicioninicialPanel.Title = 'Posicion inicial';
            app.GridLayout14 = uigridlayout(app.PosicioninicialPanel);
            app.XEditFieldLabel = uilabel(app.GridLayout14);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.GridLayout14, 'numeric');
            app.YEditFieldLabel = uilabel(app.GridLayout14);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.GridLayout14, 'numeric');
            app.ZEditFieldLabel = uilabel(app.GridLayout14);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.GridLayout14, 'numeric');
            app.E1EditFieldLabel = uilabel(app.GridLayout14);
            app.E1EditFieldLabel.Text = 'E1';
            app.E1EditField = uieditfield(app.GridLayout14, 'numeric');
            app.E2EditFieldLabel = uilabel(app.GridLayout14);
            app.E2EditFieldLabel.Text = 'E2';
            app.E2EditField = uieditfield(app.GridLayout14, 'numeric');
            app.E3EditFieldLabel = uilabel(app.GridLayout14);
            app.E3EditFieldLabel.Text = 'E3';
            app.E3EditField = uieditfield(app.GridLayout14, 'numeric');
            app.SolucionButton = uibutton(app.GridLayout14, 'push');
            app.SolucionButton.Text = 'Solucion';
            app.pipfTab = uitab(app.TabGroup3);
            app.pipfTab.Title = 'pi -> pf';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end