classdef VentanaArduino < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Ventana2                matlab.ui.Figure
        Frecuencia              matlab.ui.control.NumericEditField
        IntroducelafrecuenciademuestreoenbaudiosEditFieldLabel  matlab.ui.control.Label
        Puerto                  matlab.ui.control.EditField
        IntroduceelPuertoLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        puerto = "COM4";
        PestanaPrincipal;
        baudios = 9600;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, AppPrinc)
           app.PestanaPrincipal = AppPrinc;
        end

        % Value changed function: Puerto
        function PuertoValueChanged(app, event)
            app.puerto = app.Puerto.Value;
            
            app.Puerto.Visible = false;
            app.IntroduceelPuertoLabel.Visible = false;
            
            app.Frecuencia.Visible = true;
            app.IntroducelafrecuenciademuestreoenbaudiosEditFieldLabel.Visible = true;
        end

        % Value changed function: Frecuencia
        function FrecuenciaValueChanged(app, event)
            app.baudios = app.Frecuencia.Value;
            updateplot(app.PestanaPrincipal , app.baudios, app.puerto);
            delete(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Ventana2 = uifigure('Visible', 'off');
            app.Ventana2.Name = 'MATLAB App';
            app.IntroduceelPuertoLabel = uilabel(app.Ventana2);
            app.IntroduceelPuertoLabel.Text = 'Introduce el Puerto';
            app.Puerto = uieditfield(app.Ventana2, 'text');
            app.Puerto.ValueChangedFcn = createCallbackFcn(app, @PuertoValueChanged, true);
            app.Puerto.Value = 'COM';
            app.IntroducelafrecuenciademuestreoenbaudiosEditFieldLabel = uilabel(app.Ventana2);
            app.IntroducelafrecuenciademuestreoenbaudiosEditFieldLabel.Visible = 'off';
            app.IntroducelafrecuenciademuestreoenbaudiosEditFieldLabel.Text = 'Introduce la frecuencia de muestreo en baudios ';
            app.Frecuencia = uieditfield(app.Ventana2, 'numeric');
            app.Frecuencia.ValueChangedFcn = createCallbackFcn(app, @FrecuenciaValueChanged, true);
            app.Frecuencia.Visible = 'off';
            % Show the figure after all components are created
            app.Ventana2.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VentanaArduino(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Ventana2)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Ventana2)
        end
    end
end